classdef SpoutAdjustment_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SpoutadjustmentPanel     matlab.ui.container.Panel
        spoutOffset              matlab.ui.control.NumericEditField
        OffsetLabel_2            matlab.ui.control.Label
        RightSpoutEdit           matlab.ui.control.NumericEditField
        RightEditFieldLabel_2    matlab.ui.control.Label
        LeftSpoutEdit            matlab.ui.control.NumericEditField
        LeftEditFieldLabel_2     matlab.ui.control.Label
        WaterDispenserSpinner    matlab.ui.control.Spinner
        GiveBothWater            matlab.ui.control.Button
        GiveLeftWater            matlab.ui.control.Button
        GiveRightWater           matlab.ui.control.Button
        spoutspeed               matlab.ui.control.Spinner
        MovementdurationmsSpinnerLabel_2  matlab.ui.control.Label
        spouttoinner             matlab.ui.control.Button
        spouttoouter             matlab.ui.control.Button
        spouttozero              matlab.ui.control.Button
        KnobmaxEditField         matlab.ui.control.NumericEditField
        KnobmaxEditField_2Label  matlab.ui.control.Label
        SpoutRightKnob           matlab.ui.control.Knob
        RightspoutKnobLabel_2    matlab.ui.control.Label
        SpoutLeftKnob            matlab.ui.control.Knob
        LeftspoutKnobLabel_2     matlab.ui.control.Label
        SpoutMirrorspouts        matlab.ui.control.CheckBox
        SpoutsetouterButton      matlab.ui.control.Button
        SpoutsetinnerButton      matlab.ui.control.Button
    end

    methods (Access = public)

        function results = moveSpouts(app,l,r)
            set(app.LeftSpoutEdit,'Value',l); %update indicator
            set(app.SpoutLeftKnob,'Value',l); %update slider
            set(app.RightSpoutEdit,'Value',r); %update indicator
            set(app.SpoutRightKnob,'Value',r);  %update slider
            ls = num2str(l);rs = num2str(r); drawnow;
            teensyWrite([71 length(ls)  ls length(rs)  rs]); %move the spouts
        end

        function results = sendTeensyStepperTrialInfo(app)
            % this might be simplified in the teeny side..
            global BpodSystem
            S = BpodSystem.ProtocolSettings;
            setMotorPositions;

        end

        function results = moveHandles(app,v)
            set(app.HandlepositionKnob,'Value',v); %update slider
            v = num2str(int32(floor(v)));
            teensyWrite([72 length(v)  v]); %move the spouts
        end
    end
        methods (Access = private)
        function results = giveWater(app,valve)
            global BpodSystem

            waterAmount = app.WaterDispenserSpinner.Value; % give water for the regular time
            LeftValveTime = GetValveTimes(waterAmount, 1);
            RightValveTime = GetValveTimes(waterAmount, 2);

            if strcmp(valve,'left')
                valve = 1;
                cValveDur = LeftValveTime;
            elseif strcmp(valve,'right')
                valve = 2;
                cValveDur = RightValveTime;
            else
                valve = 3;
                cValveDur = max([LeftValveTime,RightValveTime]);
            end

            if ~BpodSystem.Status.InStateMatrix
                smavalve = NewStateMachine;
                smavalve = AddState(smavalve, 'Name', 'test', ...
                    'Timer', cValveDur,...
                    'StateChangeConditions', {'Tup', 'exit'},...
                    'OutputActions', {'ValveState', valve}); % Use 3 for valve 1 and 2 (1100) 5 for (1010)

                SendStateMachine(smavalve);
                pause(0.1);
                RunStateMachine();

            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GiveLeftWater
        function GiveLeftWaterButtonPushed(app, event)
            app.giveWater('left');
        end

        % Button pushed function: GiveRightWater
        function GiveRightWaterButtonPushed(app, event)
            app.giveWater('right');
        end

        % Value changed function: SpoutLeftKnob
        function SpoutLeftKnobValueChanged(app, event)
            value = app.SpoutLeftKnob.Value;
            set(app.LeftSpoutEdit,'Value',value);
            if app.SpoutMirrorspouts.Value
                set(app.SpoutRightKnob,'Value',value);
            end
            app.moveSpouts(app.SpoutLeftKnob.Value,app.SpoutRightKnob.Value);
        end

        % Value changing function: SpoutLeftKnob
        function SpoutLeftKnobValueChanging(app, event)
            valuel = ceil(event.Value);
            valuer = ceil(event.Value);
            set(app.LeftSpoutEdit,'Value',valuel);
            if app.SpoutMirrorspouts.Value == false
                valuer = app.SpoutRightKnob.Value;
            end
            set(app.RightSpoutEdit,'Value',valuer);
            app.moveSpouts(valuel,valuer);
        end

        % Value changed function: SpoutRightKnob
        function SpoutRightKnobValueChanged(app, event)
            value = app.SpoutRightKnob.Value;
            set(app.RightSpoutEdit,'Value',value);
            if app.SpoutMirrorspouts.Value
                set(app.SpoutLeftKnob,'Value',value);
            end
            app.moveSpouts(app.SpoutLeftKnob.Value,app.SpoutRightKnob.Value);
        end

        % Value changing function: SpoutRightKnob
        function SpoutRightKnobValueChanging(app, event)
            valuel = ceil(event.Value);
            valuer = ceil(event.Value);
            set(app.RightSpoutEdit,'Value',valuer);
            if app.SpoutMirrorspouts.Value == false
                valuel = app.SpoutLeftKnob.Value;
            end
            set(app.LeftSpoutEdit,'Value',valuel);
            app.moveSpouts(valuel,valuer);
        end

        % Value changed function: LeftSpoutEdit
        function LeftSpoutEditValueChanged(app, event)
            value = app.LeftSpoutEdit.Value;
            if value ~= app.SpoutLeftKnob.Value
                set(app.SpoutLeftKnob,'Value',value);
            end; drawnow;
            app.moveSpouts(app.SpoutLeftKnob.Value,app.SpoutRightKnob.Value);
        end

        % Value changed function: RightSpoutEdit
        function RightSpoutEditValueChanged(app, event)
            value = app.RightSpoutEdit.Value;
            if value ~= app.SpoutRightKnob.Value
                set(app.SpoutRightKnob,'Value',value);
            end; drawnow;
            app.moveSpouts(app.SpoutLeftKnob.Value,app.SpoutRightKnob.Value);
        end

        % Button pushed function: spouttozero
        function spouttozeroButtonPushed(app, event)
            moveSpouts(app,0,0)
        end

        % Button pushed function: spouttoouter
        function spouttoouterButtonPushed(app, event)
            global BpodSystem
            leftSpout = BpodSystem.ProtocolSettings.lOuterLim; %get left inner limit
            rightSpout = BpodSystem.ProtocolSettings.rOuterLim; %get right inner limit
            app.moveSpouts(leftSpout,rightSpout);
        end

        % Button pushed function: SpoutsetouterButton
        function SpoutsetouterButtonPushed(app, event)
            global BpodSystem
            BpodSystem.ProtocolSettings.lOuterLim = ceil(app.SpoutLeftKnob.Value); %get left inner limit
            BpodSystem.ProtocolSettings.rOuterLim = ceil(app.SpoutRightKnob.Value); %get right inner limit
            disp('Set outer positions')
        end

        % Button pushed function: spouttoinner
        function spouttoinnerButtonPushed(app, event)
            global BpodSystem
            leftSpout = BpodSystem.ProtocolSettings.lInnerLim; %get left inner limit
            rightSpout = BpodSystem.ProtocolSettings.rInnerLim; %get right inner limit
            app.moveSpouts(leftSpout,rightSpout)
        end

        % Button pushed function: SpoutsetinnerButton
        function SpoutsetinnerButtonPushed(app, event)
            global BpodSystem
            BpodSystem.ProtocolSettings.lInnerLim = ceil(app.SpoutLeftKnob.Value); %get left inner limit
            BpodSystem.ProtocolSettings.rInnerLim = ceil(app.SpoutRightKnob.Value); %get right inner limit
            disp('Set inner positions')
        end

        % Button pushed function: GiveBothWater
        function GiveBothWaterButtonPushed(app, event)
            app.giveWater('both');
        end

        % Value changed function: KnobmaxEditField
        function KnobmaxEditFieldValueChanged(app, event)
            value = app.KnobmaxEditField.Value;
            set(app.SpoutLeftKnob,'Limits',[0,value]);
            set(app.SpoutRightKnob,'Limits',[0,value]);
        end

        % Value changed function: spoutspeed
        function spoutspeedValueChanged(app, event)
            value = app.spoutspeed.Value;
            global BpodSystem
            app.sendTeensyStepperTrialInfo();
            BpodSystem.ProtocolSettings.SpoutSpeed = value; %update SpoutSpeed
            teensyWrite([73 length(num2str(value)) num2str(value)]); %set spout speed
        end

        % Value changed function: spoutOffset
        function spoutOffsetValueChanged(app, event)
            value = app.spoutOffset.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.spoutOffset = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpoutadjustmentPanel = uipanel(app.UIFigure);
            app.SpoutadjustmentPanel.Title = 'Spout adjustment';
            app.SpoutsetinnerButton = uibutton(app.SpoutadjustmentPanel, 'push');
            app.SpoutsetinnerButton.ButtonPushedFcn = createCallbackFcn(app, @SpoutsetinnerButtonPushed, true);
            app.SpoutsetinnerButton.Text = 'set';
            app.SpoutsetouterButton = uibutton(app.SpoutadjustmentPanel, 'push');
            app.SpoutsetouterButton.ButtonPushedFcn = createCallbackFcn(app, @SpoutsetouterButtonPushed, true);
            app.SpoutsetouterButton.Text = 'set';
            app.SpoutMirrorspouts = uicheckbox(app.SpoutadjustmentPanel);
            app.SpoutMirrorspouts.Text = 'Mirror movement';
            app.SpoutMirrorspouts.Value = true;
            app.LeftspoutKnobLabel_2 = uilabel(app.SpoutadjustmentPanel);
            app.LeftspoutKnobLabel_2.Text = 'Left spout';
            app.SpoutLeftKnob = uiknob(app.SpoutadjustmentPanel, 'continuous');
            app.SpoutLeftKnob.Limits = [0 150];
            app.SpoutLeftKnob.ValueChangedFcn = createCallbackFcn(app, @SpoutLeftKnobValueChanged, true);
            app.SpoutLeftKnob.ValueChangingFcn = createCallbackFcn(app, @SpoutLeftKnobValueChanging, true);
            app.RightspoutKnobLabel_2 = uilabel(app.SpoutadjustmentPanel);
            app.RightspoutKnobLabel_2.Text = 'Right spout';
            app.SpoutRightKnob = uiknob(app.SpoutadjustmentPanel, 'continuous');
            app.SpoutRightKnob.Limits = [0 150];
            app.SpoutRightKnob.ValueChangedFcn = createCallbackFcn(app, @SpoutRightKnobValueChanged, true);
            app.SpoutRightKnob.ValueChangingFcn = createCallbackFcn(app, @SpoutRightKnobValueChanging, true);
            app.KnobmaxEditField_2Label = uilabel(app.SpoutadjustmentPanel);
            app.KnobmaxEditField_2Label.Text = 'Knob max';
            app.KnobmaxEditField = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.KnobmaxEditField.ValueChangedFcn = createCallbackFcn(app, @KnobmaxEditFieldValueChanged, true);
            app.KnobmaxEditField.Value = 150;
            app.spouttozero = uibutton(app.SpoutadjustmentPanel, 'push');
            app.spouttozero.ButtonPushedFcn = createCallbackFcn(app, @spouttozeroButtonPushed, true);
            app.spouttozero.Text = 'zero';
            app.spouttoouter = uibutton(app.SpoutadjustmentPanel, 'push');
            app.spouttoouter.ButtonPushedFcn = createCallbackFcn(app, @spouttoouterButtonPushed, true);
            app.spouttoouter.Text = 'outer';
            app.spouttoinner = uibutton(app.SpoutadjustmentPanel, 'push');
            app.spouttoinner.ButtonPushedFcn = createCallbackFcn(app, @spouttoinnerButtonPushed, true);
            app.spouttoinner.Text = 'inner';
            app.MovementdurationmsSpinnerLabel_2 = uilabel(app.SpoutadjustmentPanel);
            app.MovementdurationmsSpinnerLabel_2.Text = 'Movement duration (ms)';
            app.spoutspeed = uispinner(app.SpoutadjustmentPanel);
            app.spoutspeed.Step = 5;
            app.spoutspeed.Limits = [0 5000];
            app.spoutspeed.ValueChangedFcn = createCallbackFcn(app, @spoutspeedValueChanged, true);
            app.spoutspeed.Value = 25;
            app.GiveRightWater = uibutton(app.SpoutadjustmentPanel, 'push');
            app.GiveRightWater.ButtonPushedFcn = createCallbackFcn(app, @GiveRightWaterButtonPushed, true);
            app.GiveRightWater.Text = 'Give water';
            app.GiveLeftWater = uibutton(app.SpoutadjustmentPanel, 'push');
            app.GiveLeftWater.ButtonPushedFcn = createCallbackFcn(app, @GiveLeftWaterButtonPushed, true);
            app.GiveLeftWater.Text = 'Give water';
            app.GiveBothWater = uibutton(app.SpoutadjustmentPanel, 'push');
            app.GiveBothWater.ButtonPushedFcn = createCallbackFcn(app, @GiveBothWaterButtonPushed, true);
            app.GiveBothWater.Text = 'Give water';
            app.WaterDispenserSpinner = uispinner(app.SpoutadjustmentPanel);
            app.WaterDispenserSpinner.Limits = [1 100];
            app.WaterDispenserSpinner.Value = 2;
            app.LeftEditFieldLabel_2 = uilabel(app.SpoutadjustmentPanel);
            app.LeftEditFieldLabel_2.Text = 'Left';
            app.LeftSpoutEdit = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.LeftSpoutEdit.ValueChangedFcn = createCallbackFcn(app, @LeftSpoutEditValueChanged, true);
            app.RightEditFieldLabel_2 = uilabel(app.SpoutadjustmentPanel);
            app.RightEditFieldLabel_2.Text = 'Right';
            app.RightSpoutEdit = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.RightSpoutEdit.ValueChangedFcn = createCallbackFcn(app, @RightSpoutEditValueChanged, true);
            app.OffsetLabel_2 = uilabel(app.SpoutadjustmentPanel);
            app.OffsetLabel_2.Text = 'spoutOffset';
            app.spoutOffset = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.spoutOffset.Limits = [1 50];
            app.spoutOffset.ValueChangedFcn = createCallbackFcn(app, @spoutOffsetValueChanged, true);
            app.spoutOffset.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpoutAdjustment_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end